classdef Tigerv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OversightTabGroup               matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        PickTableButton                 matlab.ui.control.Button
        PickFilesButton                 matlab.ui.control.Button
        resetValuesButton               matlab.ui.control.Button
        resetMapButton                  matlab.ui.control.Button
        SelectDirectoryButton           matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        DirectoryLabel                  matlab.ui.control.Label
        importMapButton                 matlab.ui.control.Button
        FlagunflagallElectrodesCheckBox  matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_22        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_21        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_20        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_19        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_18        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_17        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_16        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_15        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_14        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_13        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_12        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_11        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_10        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_9         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_8         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_7         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_6         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_5         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_4         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_3         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_2         matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_1         matlab.ui.control.CheckBox
        FlaggedLabel                    matlab.ui.control.Label
        ComfortLabel                    matlab.ui.control.Label
        ThresholdLabel                  matlab.ui.control.Label
        Comfort_22                      matlab.ui.control.NumericEditField
        Comfort_21                      matlab.ui.control.NumericEditField
        Comfort_20                      matlab.ui.control.NumericEditField
        Comfort_19                      matlab.ui.control.NumericEditField
        Comfort_18                      matlab.ui.control.NumericEditField
        Comfort_17                      matlab.ui.control.NumericEditField
        Comfort_16                      matlab.ui.control.NumericEditField
        Comfort_15                      matlab.ui.control.NumericEditField
        Comfort_14                      matlab.ui.control.NumericEditField
        Comfort_13                      matlab.ui.control.NumericEditField
        Comfort_12                      matlab.ui.control.NumericEditField
        Comfort_11                      matlab.ui.control.NumericEditField
        Comfort_10                      matlab.ui.control.NumericEditField
        Comfort_9                       matlab.ui.control.NumericEditField
        Comfort_8                       matlab.ui.control.NumericEditField
        Comfort_7                       matlab.ui.control.NumericEditField
        Comfort_6                       matlab.ui.control.NumericEditField
        Comfort_5                       matlab.ui.control.NumericEditField
        Comfort_4                       matlab.ui.control.NumericEditField
        Comfort_3                       matlab.ui.control.NumericEditField
        Comfort_2                       matlab.ui.control.NumericEditField
        Comfort_1                       matlab.ui.control.NumericEditField
        Threshold_22                    matlab.ui.control.NumericEditField
        Threshold_21                    matlab.ui.control.NumericEditField
        Threshold_20                    matlab.ui.control.NumericEditField
        Threshold_19                    matlab.ui.control.NumericEditField
        Threshold_18                    matlab.ui.control.NumericEditField
        Threshold_17                    matlab.ui.control.NumericEditField
        Threshold_16                    matlab.ui.control.NumericEditField
        Threshold_15                    matlab.ui.control.NumericEditField
        Threshold_14                    matlab.ui.control.NumericEditField
        Threshold_13                    matlab.ui.control.NumericEditField
        Threshold_12                    matlab.ui.control.NumericEditField
        Threshold_11                    matlab.ui.control.NumericEditField
        Threshold_10                    matlab.ui.control.NumericEditField
        Threshold_9                     matlab.ui.control.NumericEditField
        Threshold_8                     matlab.ui.control.NumericEditField
        Threshold_7                     matlab.ui.control.NumericEditField
        Threshold_6                     matlab.ui.control.NumericEditField
        Threshold_5                     matlab.ui.control.NumericEditField
        Threshold_4                     matlab.ui.control.NumericEditField
        Threshold_3                     matlab.ui.control.NumericEditField
        Threshold_2                     matlab.ui.control.NumericEditField
        Threshold_1                     matlab.ui.control.NumericEditField
        ExperimentTabGroup              matlab.ui.container.TabGroup
        TestSequenceTab                 matlab.ui.container.Tab
        TestSeq_PulsesLabel             matlab.ui.control.Label
        Testseq_ResetAmplitudes         matlab.ui.control.Button
        TestSeq_ResetElectrodes         matlab.ui.control.Button
        InterpulsegapmsLabel_3          matlab.ui.control.Label
        TestSeq_Amplitudes              matlab.ui.control.EditField
        InterpulsegapmsLabel_2          matlab.ui.control.Label
        TestSeq_Electrodes              matlab.ui.control.EditField
        TestSeq_Run                     matlab.ui.control.Button
        TestSeq_Filename                matlab.ui.control.Label
        TestSeq_Plot                    matlab.ui.control.UIAxes
        DiscrimationExpTab              matlab.ui.container.Tab
        Discrimination_ClearTable       matlab.ui.control.Button
        Discrimination_TablenameLabel   matlab.ui.control.Label
        Discrimination_SaveTableButton  matlab.ui.control.Button
        Continue_ReturnButton           matlab.ui.control.Button
        Discrimination_Table            matlab.ui.control.Table
        Discrimination_RuntimeLabel     matlab.ui.control.Label
        Discrimination_RunsEditField    matlab.ui.control.EditField
        RunsLabel_2                     matlab.ui.control.Label
        PuttextforListLabel             matlab.ui.control.Label
        Continue_RunButton              matlab.ui.control.Button
        Discrimination_AddfilesButton   matlab.ui.control.Button
        Discrimination_ContinueButton   matlab.ui.control.Button
        Continue_ListBox                matlab.ui.control.ListBox
        Discrimination_Plot             matlab.ui.control.UIAxes
        RankingExpTab                   matlab.ui.container.Tab
        Ranking_ClearTable              matlab.ui.control.Button
        Ranking_TablenameLabel          matlab.ui.control.Label
        Ranking_SaveTableButton         matlab.ui.control.Button
        Ranking_RuntimeLabel            matlab.ui.control.Label
        Ranking_Table                   matlab.ui.control.Table
        Ranking_AddfilesButton          matlab.ui.control.Button
        Ranking_RunsEditField           matlab.ui.control.EditField
        RunsLabel                       matlab.ui.control.Label
        Ranking_RunButton               matlab.ui.control.Button
        Ranking_Plot                    matlab.ui.control.UIAxes
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        GenBurstTab                     matlab.ui.container.Tab
        ZoomfactorSpinner               matlab.ui.control.Spinner
        ZoomfactorSpinnerLabel          matlab.ui.control.Label
        IPGInfo_2                       matlab.ui.control.Label
        IPGInfo_1                       matlab.ui.control.Label
        IPGDisplay                      matlab.ui.control.Label
        MaxPulsesLabel                  matlab.ui.control.Label
        RateEditField_inms              matlab.ui.control.NumericEditField
        BurstperiodppsLabel_2           matlab.ui.control.Label
        FrameperiodLabel                matlab.ui.control.Label
        WaitPeriodEditField             matlab.ui.control.NumericEditField
        BurstGapmsLabel                 matlab.ui.control.Label
        InterpulsegapEditField          matlab.ui.control.NumericEditField
        PulsesperburstEditField         matlab.ui.control.NumericEditField
        PulsesperFrameLabel             matlab.ui.control.Label
        InterpulsegapmsLabel            matlab.ui.control.Label
        RateEditField_inpps             matlab.ui.control.NumericEditField
        BurstperiodppsLabel             matlab.ui.control.Label
        InputgapsButton                 matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        SaveAsButton                    matlab.ui.control.Button
        Burst_Example                   matlab.ui.control.UIAxes
        DataAnalysisTab                 matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        DiscriminationPlotTab           matlab.ui.container.Tab
        RankingPlotTab                  matlab.ui.container.Tab
        needautomatedlognamingLabel     matlab.ui.control.Label
        nameofLogorderorsoLabel         matlab.ui.control.Label
        createPlotButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        usuallydirectorylikenormallydisorrankinglogfolderLabel  matlab.ui.control.Label
        FileorFoldertouseEditField      matlab.ui.control.EditField
        FileorFoldertouseEditFieldLabel  matlab.ui.control.Label
        PatientNameEditField            matlab.ui.control.EditField
        PatientNameEditFieldLabel       matlab.ui.control.Label
        giveinputdataButton             matlab.ui.control.Button
        AppSettingsTab                  matlab.ui.container.Tab
        InterpulsegapmsLabel_6          matlab.ui.control.Label
        RampingLength_inmsEditField     matlab.ui.control.NumericEditField
        InterpulsegapmsLabel_5          matlab.ui.control.Label
        Initiate_Amplitudes             matlab.ui.control.NumericEditField
        Initiate_Electrodes             matlab.ui.control.EditField
        InterpulsegapmsLabel_4          matlab.ui.control.Label
        TokenOrderDropDown              matlab.ui.control.DropDown
        TokenOrderDropDownLabel         matlab.ui.control.Label
        TokenpatternDropDown            matlab.ui.control.DropDown
        TokenpatternDropDownLabel       matlab.ui.control.Label
        RandomTokenOrderCheckBox        matlab.ui.control.CheckBox
        BuildframerateLabel             matlab.ui.control.Label
        SequenceGenerationLabel         matlab.ui.control.Label
        OverallLabel                    matlab.ui.control.Label
        GenerateLogCheckBox             matlab.ui.control.CheckBox
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDown_2Label             matlab.ui.control.Label
        Intersequence_inmsEditField     matlab.ui.control.NumericEditField
        IntersequencemsEditFieldLabel   matlab.ui.control.Label
        ClevelsPulsewidthEditField      matlab.ui.control.NumericEditField
        ClevelsPulsewidthEditFieldLabel  matlab.ui.control.Label
        TransfertoNICCheckBox           matlab.ui.control.CheckBox
        Stimduration_inmsEditField      matlab.ui.control.NumericEditField
        StimdurationmsEditFieldLabel    matlab.ui.control.Label
        InterphasegapsEditField         matlab.ui.control.NumericEditField
        InterphasegapsEditFieldLabel    matlab.ui.control.Label
        StimphaseEditField              matlab.ui.control.NumericEditField
        StimphasesEditFieldLabel        matlab.ui.control.Label
        PitchRankingDecisionRepetitionEditField  matlab.ui.control.NumericEditField
        PitchRankingDecisionRepetitionEditFieldLabel  matlab.ui.control.Label
        PitchRankingExperimentLabel     matlab.ui.control.Label
        Decideafter2unanimousDecisionsCheckBox  matlab.ui.control.CheckBox
        RampingCheckBox                 matlab.ui.control.CheckBox
        CalculateasDynamicRangeofMapCheckBox  matlab.ui.control.CheckBox
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)

        Unified
        TestSeq        % This will hold all Test Sequence-related data 
        IPG
        roundedpossibleFrames

        frame_rate_micros = 64.8; %frame period harcoded to 64.8 - for now hardcoded in μs 
        frame_rate_millis = 64.8/1e3; %frame period harcoded to 64.8 - for now hardcoded in ms 
        possibleFrames % possible Frames for the pps/frame_rate
        fractional_possibleFrames % 0.87 of possibleFrames
        stepsize % basiclly framerate currently
        interpulsegaps % actual gaps + the frame of pulse before 


        ComfortValues double = zeros(1,22); % preallocate Comforts
        ThresholdValues double = zeros(1,22); % preallocate Threshold
        FlagElectrodeCheckBoxValues logical = false(1,22); % preallocate Flags

        PatientID
        Directory
        
        startPath = pwd; % present working directory
        % startPath = 'C:\'; % main C folder
        brustsfolder = "Burst_structs";
        
        
        patientFolder
        administrator = {'admin', 'admin'};
        isLoginSuccess = 0; 


        isactiveDynamicRange
        isactiveTransfer
        isactiveRamping
        isactiveLogging

        % UIFigure
        % ... your existing properties ...

        MasterTable table


    end
    
    methods (Access = private)

        function MapValueChanged(app, event, valuesArrayName)
            src = event.Source;
            value = src.Value;
            name = src.Tag;   % e.g. "C7"
        
            idx = ExtractIndexFromTag(app, name);  

            if valuesArrayName == "FlagElectrodeCheckBox"
                app.(valuesArrayName)(idx) = value; % Store true/false
                % Add any specific checkbox logic here (e.g., disabling the row)
                return; 
            end

            app.(valuesArrayName)(idx) = value;   % store value

            % 1. Define the rules once (could even be in your startupFcn)
            rules.ComfortValues   = struct('opposite', "Threshold", 'limit', 2);
            rules.ThresholdValues = struct('opposite', "Comfort",   'limit', 1);
            
            if isfield(rules, valuesArrayName)
                % 2. Look up the settings based on the input name
                config = rules.(valuesArrayName);
                
                % 3. Apply the values
                compName = config.opposite + "_" + idx;
                app.(compName).Limits(config.limit) = value;
            end
        end

        function idx = ExtractIndexFromTag(~, tag)
            % Extracts the numeric part of a tag string
            numStr = regexp(tag, '\d+', 'match');  % find all numbers
            idx = str2double(numStr{1});           % convert first match to number
        end

        function setupMapValues(app, prefix, valuesArrayName)
            for i = 1:22
                compName = prefix + "_" + i;
                app.(compName).Tag = prefix(1)+ "_" + i; % C or T or F
                app.(compName).ValueChangedFcn = @(src,event) MapValueChanged(app,event,valuesArrayName);
                app.(valuesArrayName)(i) = app.(compName).Value;
            end
        end

        function oppositeArrayName = GetOppositeArray(~, valuesArrayName) % not used currently
            % Returns the opposite array name for sliders/values
            switch valuesArrayName
                case "ComfortValues"
                    oppositeArrayName = "ThresholdValues";
                case "ThresholdValues"
                    oppositeArrayName = "ComfortValues";
                otherwise
                    errordlg("No valuesArrayName") % or error/warning if needed
            end
        end

        function stopFlag = CheckMapLevels(app, idx, name, value, valuesArrayName) % error if T is over C % not used rn  
            stopFlag = false;

            switch valuesArrayName
                case "ThresholdValues"
                    if value > app.ComfortValues(idx) 
                        errordlg(name + " is over Comfort!")
                        stopFlag = true;
                    end
                case "ComfortValues"
                    if value < app.ThresholdValues(idx) 
                        errordlg(name + " is under Threshold!")
                        stopFlag = true;
                    end
            end
        end
        
        function CreatePatientFolder(app) 
            tString = GetCurrentTimeStamp(app);
            currentFolder = app.PatientID + tString;

            app.patientFolder = fullfile(app.Directory, currentFolder);
            if ~exist(app.patientFolder, 'dir')
                mkdir(app.patientFolder);
            end
        end
        
        function tString = GetCurrentTimeStamp(~)
            t = datetime('now');
            tString = datestr(t, 'HH.MM_dd_MM_yyyy'); % Format: HH.MM_dd_MM_yyyy
        end

        function GenerateSequenceZoomedPlot(app)
            ZoomFactor = app.ZoomfactorSpinner.Value; 
            runlength = 100; % ms
            
            burst_struct = GenerateBurstStruct(app);

            % 3. Construct the full sequence
            seq_numbursts = ceil(runlength / app.RateEditField_inms.Value);
            seq_current = repmat(burst_struct, 1, seq_numbursts);

            time_ms = (0:length(seq_current)-1) * app.frame_rate_millis;

            % 3. Plot using the time vector
            plot(app.Burst_Example, time_ms, seq_current);
            
            % 4. Adjust the X-Limit to show the zoomed portion in milliseconds
            % This will now correctly show the 3.333ms bursts
            max_time = max(time_ms);
            xlim(app.Burst_Example, [0, max_time / ZoomFactor]);
            
            % Optional: Label your axes so you can verify the units
            xlabel(app.Burst_Example, 'Time (ms)');
            ylabel(app.Burst_Example, 'Amplitude');

        end
        
        function GenerateSeqTabStart(app)
            app.IPG = struct();
            app.IPG.isChanged = 0;
            app.IPG.stepsize = app.frame_rate_millis;

            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            app.RateEditField_inms.Value = round(snapped_value, 4);
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value;
            app.WaitPeriodEditField.Value = app.RateEditField_inms.Value-sum(app.interpulsegaps); % update WaitperiodEditfield

            app.FrameperiodLabel.Text = "Frameperiod [μs]: " + app.frame_rate_micros; % this needs to be updated if not hardcoded anymore

            UpdateMaxPulses(app);
            UpdateInterpulseGaps(app);
        end
        
        function UpdateInterpulseGaps(app)
            
            gapString = strjoin(string(app.interpulsegaps), ', '); % Convert the numeric array to strings, then join them with a comma
            
            app.IPGDisplay.Text = "Interpulsegaps: [" + gapString + "]"; % update the label 
        end
        
        function UpdateMaxPulses(app)

            app.possibleFrames = app.RateEditField_inms.Value/app.frame_rate_millis; % calc max possible frames
            app.roundedpossibleFrames = floor(app.possibleFrames);

            app.fractional_possibleFrames = round(rem(app.possibleFrames, 1), 4); % get the fractional

            app.MaxPulsesLabel.Text = "Maximum Pulses: " + app.possibleFrames; % write the label 

            app.PulsesperburstEditField.Limits(2) = app.roundedpossibleFrames; % limit the max amount of pulses
        end
        
        function [multipleofFrame, middleNumofFrames] = GetbiggestpossiblemultipleofFrameRate(app)
            
            rawIpgValue = app.RateEditField_inms.Value / (app.PulsesperburstEditField.Value-1);

            multipleofFrame = floor(rawIpgValue/app.IPG.stepsize) * app.IPG.stepsize;

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end

            maxIPG = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % interpulsegap Limit
               
            if multipleofFrame > maxIPG, multipleofFrame = maxIPG; end

            middleNumofFrames = round((rawIpgValue/app.IPG.stepsize)/2) * app.IPG.stepsize;

        end
        
        function ResetInterpulseGap(app)
            app.interpulsegaps(:) = app.InterpulsegapEditField.Value;
            UpdateInterpulseGaps(app);
        end
        
        function GenerateFileName(app) % check better naming possiblity
                        
            
            if ~isempty(app.interpulsegaps)
                gapString = strjoin(string(app.interpulsegaps), ', ');
                equalinterpulsegaps = sprintf('%.4f', app.InterpulsegapEditField.Value); % maybe later file to long 
                isUniform = all(app.interpulsegaps(:) == app.interpulsegaps(1));
            end

            % Determine the filename based on pulse count and uniformity
            if app.PulsesperburstEditField.Value == 1
                % Single pulse: No gap (D) needed in filename
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_W', num2str(app.WaitPeriodEditField.Value));
                    
            elseif isUniform
                % Multiple pulses, all gaps are the same
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_G', num2str(equalinterpulsegaps), '_W', num2str(app.WaitPeriodEditField.Value));
                    
            else
                % Not uniform (and implied pulses ~= 1)
                app.FilenameEditField.Value = strcat('R', num2str(app.RateEditField_inms.Value), '_P', num2str(app.PulsesperburstEditField.Value), ...
                    '_G', num2str(gapString), '_W', num2str(app.WaitPeriodEditField.Value));
            end

        end
        
        function burst_struct = GenerateBurstStruct(app)

            % 1. Calculate the total frames in ONE burst period
            % This ensures the pulses are spaced out correctly when repeated
            frames_per_burst = round(app.RateEditField_inms.Value / app.frame_rate_millis); % should be an integer alr, round is just tomake sure 
            burst_struct = zeros(1, frames_per_burst); % amount of frames in the struct
            
            burst_numpulses = app.PulsesperburstEditField.Value; % number of pulses
            
            % 2. Calculate pulse positions relative to the start of the burst
            if ~isempty(app.interpulsegaps) && burst_numpulses > 0

                current_frame = 1; % First pulse at frame 1
                burst_struct(current_frame) = 1; % Set amplitude

                for i = 1:(burst_numpulses - 1)

                    % Move forward by the gap + 1 pulse width
                    gap_in_frames = round(app.interpulsegaps(i) / app.frame_rate_millis); % also int alr % minus frame to get actual ipg without the pulsetime
                    current_frame = current_frame + gap_in_frames;
                
                    % Safety check: don't exceed the burst period length
                    if current_frame <= frames_per_burst
                        burst_struct(current_frame) = 1;
                    end
                end

            end
        end
        
        function resetMapValues(app, prefix, valuesArrayName)
            % 1. Define the rules
            % 'limit' here refers to the boundary we want to reset for the OTHER component
            rules.ComfortValues   = struct('startValue', 255,   'resetLimits', [0 255], 'isNumeric', true,  "isCheckbox", false);
            rules.ThresholdValues = struct('startValue', 0,   'resetLimits', [0 255], 'isNumeric', true,  "isCheckbox", false);
            rules.FlagElectrodeCheckBoxValues = struct('startValue', 0, 'isNumeric', false, "isCheckbox", true);
            
            if ~isfield(rules, valuesArrayName)
                return; % Safety check
            end
            
            config = rules.(valuesArrayName);

            if config.isCheckbox
                app.FlagunflagallElectrodesCheckBox.Value = false;
            end
            
            for i = 1:length(app.(valuesArrayName))
                compName = prefix + "_" + i;
                target = app.(compName);

                if config.isNumeric
                    target.Limits = [0 255]; % Open limits to avoid "out of range" errors
                end

                target.Value = config.startValue; % Set numeric value
                app.(valuesArrayName)(i) = target.Value;  % Sync the internal array
            end

        end

        function amplitudeList = CalculateDynamicRange(app, amplitudes, electrodes)
            % electrodes = electrodesList{i} - specific electrode indices (e.g., [3,7,12])
            numElectrodes = length(electrodes);
            amplitudeList = zeros(size(amplitudes));
            
            for j = 1:numElectrodes
                electrodeID = electrodes(j);  % Specific electrode value, NOT position
                threshold = app.ThresholdValues(electrodeID);
                comfort = app.ComfortValues(electrodeID);
                
                % Scale input amplitude for this specific electrode
                amplitudeList(j) = threshold + (comfort - threshold) * amplitudes(j);
            end
        end
        
        function sequencePattern = GenerateTokenPattern(app, File, Tab, Tokenpattern)
            
        end

        function sequence = genereateSequence(app, File, Tab) % Token will be used later depending on the picked exp 

            burst = generateBurst(app, File, Tab); 

            numFiles = length(File);
            burstRepetitions = cell(1, numFiles);
            sequence = cell(1, numFiles);

            for i = 1:numFiles
                burstLength = length(File(i).burst_struct) * app.frame_rate_millis;
                burstRepetitions{i} = round(app.Stimduration_inmsEditField.Value / burstLength);

                sequence{i} = struct();
                
                sequence{i}.electrodes = repmat(burst{i}.electrodes, 1, burstRepetitions{i});
                sequence{i}.amplitudes = repmat(burst{i}.amplitudes, 1, burstRepetitions{i});


                if app.isactiveRamping 
                    [rampUpBurst, rampDownBurst, numRampBursts] = GenerateRamps(app, burst{i});
                            
                    fullBursts = max(0, burstRepetitions{i} - 2 * numRampBursts);
                    fullBurstSeq.amplitudes = repmat(burst{i}.amplitudes, 1, fullBursts);
                    
                    sequence{i}.amplitudes = [rampUpBurst.amplitudes, fullBurstSeq.amplitudes, rampDownBurst.amplitudes];
                end
            end 
        end

        function burst = generateBurst(app, File, Tab)

            %data.TestSeq = struct('electrodes', app.TestSeq.electrodes, 'amplitudes', app.TestSeq.amplitudes);
            %data.Discrimination = struct();
            %data.Ranking = struct();

            %config = rules.(Tab);

            % Parse amplitudes from EditField
            %amplitudeList = str2double(split(app.TestSeq_Electrodes.Value, ','));
            
            % Assume pulseStructs is your 1x10 struct array (one struct per pulse)

            % % Access table data
            % tableData = app.YourTableName.Data;  % Gets entire table as table
            % 
            % % Access specific column (e.g., 'Electrodes' column)
            % electrodesCol = tableData.Electrodes;  % or tableData{:,'Electrodes'}
            % 
            % % Convert to numeric array (if needed)
            % electrodes = str2double(split(string(electrodesCol), ','));
            % 
            % % For a specific row (e.g., row 3)
            % electrodesRow3 = tableData.Electrodes{3};  % Cell array
            % electrodesRow3 = str2double(split(electrodesRow3, ','));
            % 
            % % Or using row and column indices
            % electrodeValue = tableData{3, 'Electrodes'};  % Row 3, Electrodes column
           
            numFiles = length(File);
            burst = cell(1, numFiles);

            Tab_Electrodes = cell(1, numFiles); 
            Tab_Amplitudes = cell(1, numFiles);

            electrodesList = cell(1, numFiles);
            amplitudesList = cell(1, numFiles);

            for i = 1:numFiles

            Tab_Electrodes{i} = string(Tab) + "_Electrodes";
            Tab_Amplitudes{i} = string(Tab) + "_Amplitudes";

            electrodesList{i} = str2double(split(app.(Tab_Electrodes{i}).Value, ',')); % generates List of electrodes in one burst
            
            amplitudesList{i} = str2double(split(app.(Tab_Amplitudes{i}).Value, ','));

            if app.isactiveDynamicRange
                amplitudeList = CalculateDynamicRange(app, amplitudesList{i}, electrodesList{i});
                amplitudesList{i} = amplitudeList;
            end

            % Get the original burst pattern (preserves 0s and 1s)
            burst_array = File(i).burst_struct;  % Your 1xN logical/numeric array
            
            % Create full arrays with 0s where no pulse
            burst{i} = struct();
            burst{i}.burst_pattern = burst_array;  % Keep original pattern
            
            % Reconstruct FULL arrays matching burst length
            full_length = length(burst_array);
            burst{i}.electrodes = zeros(1, full_length);  % All 0s initially
            burst{i}.amplitudes = zeros(1, full_length);  % All 0s initially
            
            % Fill ONLY active positions
            active = find(burst_array == 1);
            
                if length(active) == length(electrodesList{i})
                    burst{i}.electrodes(active) = electrodesList{i};
                else
                    warning('Electrodes mismatch: %d active pulses vs %d electrodes', ...
                            length(active), length(electrodesList{i}));
                end
                
                if length(active) == length(amplitudesList{i})
                    burst{i}.amplitudes(active) = amplitudesList{i};
                else
                    warning('Amplitudes mismatch: %d active pulses vs %d amplitudes', ...
                            length(active), length(amplitudesList{i}));
                end
            end
        end
        
        function GenerateTestSeq(app, burstFile)
            % as Tab selection Callback maybe
            sequence = genereateSequence(app, burstFile, "TestSeq");
            % app.TestSeq.
            GeneratePlot(app, sequence{1}, "TestSeq"); % 1 for now later change on row selection, also later create with token pattern

        end
        
        function GenerateAppSettingsStart(app)
            app.isactiveDynamicRange = app.CalculateasDynamicRangeofMapCheckBox.Value;
            app.isactiveTransfer = app.TransfertoNICCheckBox.Value;
            app.isactiveRamping = app.RampingCheckBox.Value;
            app.isactiveLogging = app.GenerateLogCheckBox.Value;
        end
        
        function electrodesDisplay = SetElectrodesInput(app, File)
           
            numPulses = nnz(File.burst_struct);
            electrodes = app.Initiate_Electrodes.Value; 
            electrodeList = repmat({electrodes}, 1, numPulses);  % {"E1","E1","E1"...}
            electrodesDisplay = strjoin(electrodeList, ','); % seperate this move out if fjunction
        end
        
        function amplitudesDisplay = SetAmplitudesInput(app, File)
            
            numPulses = nnz(File.burst_struct);
            amplitudes = app.Initiate_Amplitudes.Value; 
            amplitudesStr = num2str(amplitudes);
            amplitudeList = repmat({amplitudesStr}, 1, numPulses);  % {"E1","E1","E1"...}
            amplitudesDisplay = strjoin(amplitudeList, ','); % seperate this move out if fjunction
        end
        
        function SetTestSeqInputs(app, setting, File)

            app.Testseq_ResetAmplitudes.Enable = setting;
            app.TestSeq_ResetElectrodes.Enable = setting;

            app.TestSeq_Amplitudes.Enable = setting;
            app.TestSeq_Electrodes.Enable = setting;

            app.TestSeq_Run.Enable = setting;
            if ~isempty(File)
                numPulses = nnz(File(1).burst_struct);
            else
                numPulses = 0;
            end

            app.TestSeq_PulsesLabel.Text = "Pulses in Burst File: " + num2str(numPulses);

            if strcmp(setting, "off")
                cla(app.TestSeq_Plot);
                app.TestSeq_Filename.Text = "Please select only a single Sequence to Test!";
            end
        end
        
        function GeneratePlot(app, sequencePattern, Tab)

            % Generate the plot (standalone version)
            Plot = app.(Tab + "_Plot");  % Dynamic plot handle
            timeframe = (1:length(sequencePattern.amplitudes)) * app.frame_rate_millis;  % Time axis in ms
            
            % Create the plot
            plot(Plot, timeframe', sequencePattern.amplitudes');
            xlim(Plot, [0 max(timeframe)]);                    % X: 0 to total duration
            ylim(Plot, [0 max(sequencePattern.amplitudes)*1.2]);     % Y: 0 to 120% max amplitude
            
            % Optional: Add labels for clarity
            xlabel(Plot, 'Time (ms)');
            ylabel(Plot, 'Amplitude');
            title(Plot, sprintf('Sequence Amplitudes - Duration: %.1f ms', max(timeframe)));
            grid(Plot, 'off');

        end
        
        function [rampUpBurst, rampDownBurst, numRampBursts] = GenerateRamps(app, burst)
            burstLength = length(burst.amplitudes);  % 17
            burstLength_ms = burstLength * app.frame_rate_millis;  % Scalar!
            
            numRampBursts = round(app.RampingLength_inmsEditField.Value / burstLength_ms);

            % if ~isscalar(numRampBursts), numRampBursts = round(mean(numRampBursts)); end
            if numRampBursts < 1, numRampBursts = 1; end
            
            totalLength = numRampBursts * burstLength;  % Scalar: 1*17=17, 9*17=153, etc.
            rampUpScale = linspace(0.1, 1, totalLength);
            rampDownScale = linspace(1, 0.1, totalLength);
            
            fullOriginalAmp = repmat(burst.amplitudes, 1, numRampBursts);  % [1 x totalLength]
            
            rampUpBurst = burst; rampUpBurst.amplitudes = fullOriginalAmp .* rampUpScale;
            rampDownBurst = burst; rampDownBurst.amplitudes = fullOriginalAmp .* rampDownScale;
        end
        
        function SendtoNic(app, sequencePattern, File)

            logfilename = string(app.Directory) + '\' + ...
                                string(app.PatientIDEditField.Value) + '\' + ...
                                string(File) + '.txt'; % add a folder 
            

            properties = java.util.HashMap;
            properties.put('platform', 'SP16'); % could change the platform
            properties.put('implant', 'CIC4'); % could change the implant
            properties.put('mode', app.ModeDropDown.Value);
            properties.put('min_pulse_width_us', '25.0'); % pulse width adjustaböe
            properties.put('go_live', 'off'); % go live 
            
            properties.put('log_stream_format', 'basic'); % 
            properties.put('log_stream_filename', char(logfilename));
            
            properties.put('auto_pufs', 'on');
            properties.put('verbose', 'on');
            properties.put('flagged_electrodes', app.FlagElectrodeCheckBoxValues);
            properties.put('latency_ms', '100');
            % properties.put('c_levels_pulse_width_us', num2str(app.c_levels_pulse_width.Value));
            properties.put('c_levels', app.c_lvls);
            client = com.cochlear.nic.nic4.Streamer(properties);
            
            client.start();
            client.sendData(cochlear.nic.nic4.struct2map(sequencePattern));
            client.waitUntilFinished();
            client.stop();
            client.close();
        end
        
        function InitiateFileTable(app, File)
            
            numFiles = length(File);
            
            Filename = cell(numFiles, 1);
            electrodeCol = cell(numFiles, 1);
            amplitudeCol = cell(numFiles, 1);
            
            for i = 1:numFiles
                Filename{i} = app.Unified.Filename{i};
                electrodeCol{i} = SetElectrodesInput(app, File(i).data);
                amplitudeCol{i} = SetAmplitudesInput(app, File(i).data);
            end
            
            removeCol = repmat({'remove'}, numFiles, 1);
            
            % Create table data ONLY
            tableData = table(Filename, electrodeCol, amplitudeCol, removeCol);
            tableData.Properties.RowNames = Filename;
            
            % UPDATE UI TABLES + HEADERS DIRECTLY
            app.Unified.MasterTable = tableData;
            UpdateUnifiedTables(app);  % Helper function below
        end


            % DiscriminationTable callback → syncs RankingTable
            function DiscriminationTableCellEdit(app, event)
                % Update unified source
                app.Unified.File = app.DiscriminationTable.Data;
                
                % Auto-sync other table
                app.RankingTable.Data = app.DiscriminationTable.Data;
            end
            
            % RankingTable callback → syncs DiscriminationTable  
            function RankingTableCellEdit(app, event)
                app.Unified.File = app.RankingTable.Data;
                app.DiscriminationTable.Data = app.RankingTable.Data;
            end
            
            function UpdateUnifiedTables(app)
                master = app.Unified.MasterTable;
                app.Discrimination_Table.Data = master;
                app.Ranking_Table.Data = master;
                app.Discrimination_Table.ColumnName = master.Properties.VariableNames;
                app.Ranking_Table.ColumnName = master.Properties.VariableNames;
            end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            setupMapValues(app, "Comfort", "ComfortValues");
            setupMapValues(app, "Threshold", "ThresholdValues");
            setupMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues");

            app.PatientID = app.PatientIDEditField.Value;
            app.DirectoryLabel.Text = "Directory: " + app.startPath;

            GenerateSeqTabStart(app);
            GenerateAppSettingsStart(app);

            app.TestSeq = struct();
            app.TestSeq.File = []; % generate as a struct with amplitudes sequences electrodes patterns logfilenames and folders 

            app.Unified = struct();
            app.Unified.File = [];  
            app.Unified.MasterTable = table();
            
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientID = value;
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)

            % Open the folder selection dialog
            folderName = uigetdir(app.startPath, 'Select the Directory');
            
            if isequal(folderName, 0)
                return; 
            end

            app.Directory = folderName;
            app.DirectoryLabel.Text = ["Directory:", app.Directory];
        end

        % Callback function
        function OversightTabGroupSelectionChanged(app, event)
            % to activate Log in just put this callback in the
            % selectionChangedFcn on app.OversightTabGroup
            if app.isLoginSuccess == 0 
                app.OversightTabGroup.SelectedTab = app.MainTab;
                errordlg('You must log in to access this tab','Error')
            end
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            prompt = {'Enter Username:','Enter Password:'};
            dlgtitle = 'Log in';
            dims = [1 35];
            definput = {'',''};
            loginDetails = inputdlg(prompt,dlgtitle,dims,definput);

            userEntry = string(strtrim(loginDetails));
            administartorEntry = string(strtrim(app.administrator));

            if isempty(loginDetails), return; end

            if isequal(userEntry(:), administartorEntry(:)), app.isLoginSuccess = 1; app.LoginButton.Enable = false; app.LoginButton.Text = "Logged in"; end
        end

        % Value changed function: FlagunflagallElectrodesCheckBox
        function FlagunflagallElectrodesCheckBoxValueChanged(app, event)
            value = app.FlagunflagallElectrodesCheckBox.Value;

            app.FlagElectrodeCheckBoxValues(:) = value;

            for i = 1:length(app.FlagElectrodeCheckBoxValues)
                compName = sprintf('FlagElectrodeCheckBox_%d', i); 
                % Update the UI component directly
                app.(compName).Value = value;
            end
            
        end

        % Value changed function: PulsesperburstEditField
        function PulsesperburstEditFieldValueChanged(app, event)
            value = app.PulsesperburstEditField.Value;
            numIPGs = value - 1;
            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % biggest possbile wait for those many pulses

            if value > 1
                app.possibleFrames = app.RateEditField_inms.Value/app.frame_rate_millis;
                app.roundedpossibleFrames = floor(app.possibleFrames);

                app.InterpulsegapEditField.Enable = "on";
                app.InputgapsButton.Enable = "on";
                numIPG = app.PulsesperburstEditField.Value-1;
                if numIPG < 1, numIPG = 1; end
                app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield
                            
                [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
                ipgValue = middleNumofFrame;

                app.interpulsegaps = ones(1,numIPGs) * ipgValue; % newly initiates the Array

                app.InterpulsegapEditField.Value = round(ipgValue, 4);

                app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

                UpdateInterpulseGaps(app); % writes the IPG Label 
            else
                app.interpulsegaps = [];
                app.WaitPeriodEditField.Value = app.RateEditField_inms.Value;
                UpdateInterpulseGaps(app);
                app.InterpulsegapEditField.Enable = "off";
                app.InputgapsButton.Enable = "off";
            end

            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: RateEditField_inms
        function RateEditField_inmsValueChanged(app, event)
            % Calculate the nearest multiple
            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            
            % Update the UI so the user sees the actual value being used
            app.RateEditField_inms.Value = round(snapped_value, 4);
            
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

            UpdateMaxPulses(app);

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield
            
            [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
            ipgValue = middleNumofFrame;

            if ~isempty(app.interpulsegaps)
                app.interpulsegaps = ones(1,numIPG) * ipgValue;
                app.InterpulsegapEditField.Value = ipgValue;
                UpdateInterpulseGaps(app); % writes the IPG Label 
            end    

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % limit the waitperiod to the Rate 
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            ResetInterpulseGap(app);
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: RateEditField_inpps
        function RateEditField_inppsValueChanged(app, event)

            app.RateEditField_inms.Value = 1e3/app.RateEditField_inpps.Value; % get in ms 

            snapped_value = round(app.RateEditField_inms.Value / app.frame_rate_millis) * app.frame_rate_millis;
            
            % Update the UI so the user sees the actual value being used
            app.RateEditField_inms.Value = round(snapped_value, 4);
            
            app.RateEditField_inpps.Value = 1e3/app.RateEditField_inms.Value; % get in pps 

           
            UpdateMaxPulses(app);

            [~, middleNumofFrame] = GetbiggestpossiblemultipleofFrameRate(app);
            ipgValue = middleNumofFrame;
            
            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            app.InterpulsegapEditField.Limits(2) = round(app.frame_rate_millis * ((floor(app.roundedpossibleFrames / numIPG) * numIPG)/numIPG), 4); % Limit the Inputfield

            if ~isempty(app.interpulsegaps)
                app.interpulsegaps = ones(1,numIPG) * ipgValue;
                app.InterpulsegapEditField.Value = round(ipgValue, 4);
                UpdateInterpulseGaps(app); % writes the IPG Label 
            end % newly initiates the Array        
            

            app.WaitPeriodEditField.Limits(2) = app.RateEditField_inms.Value; % limit the waitperiod to the Rate 
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            ResetInterpulseGap(app);
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: InterpulsegapEditField
        function InterpulsegapEditFieldValueChanged(app, event)
            if app.InterpulsegapEditField.Value < app.frame_rate_millis, app.InterpulsegapEditField.Value = app.frame_rate_millis; end

            value = round(app.InterpulsegapEditField.Value / app.IPG.stepsize) * app.IPG.stepsize;

            app.InterpulsegapEditField.Value = round(value, 4); % should calc the next working multiple of app.framerate

            app.interpulsegaps(:) = app.InterpulsegapEditField.Value; % all of ipg array gets set to 

            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);

            UpdateInterpulseGaps(app); % writes the IPG Label 

            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Button pushed function: InputgapsButton
        function InputgapsButtonPushed(app, event)
            persistent Input

            numIPGs = app.PulsesperburstEditField.Value - 1;
            defaultInput = [];

            for i = 1:numIPGs
                defaultInput = strcat(defaultInput,num2str(app.InterpulsegapEditField.Value));
                if i<numIPGs, defaultInput = strcat(defaultInput,","); end
            end

            if isempty(Input) || app.IPG.isChanged
                app.IPG.isChanged = 0;
                Input = defaultInput;
            end
           
            prompt = 'Enter desired IPGs:';
            dlgtitle = 'Input multiple IPGs';
            dims = [1 170];
            answer = inputdlg(prompt,dlgtitle,dims,Input);

            userInput = str2num(answer{1}); 

            if length(userInput) ~= numIPGs
                return;
            end

            numIPG = app.PulsesperburstEditField.Value-1;
            if numIPG < 1, numIPG = 1; end
            maxIPG = app.frame_rate_millis*(floor(app.roundedpossibleFrames / numIPG) * numIPG)/(app.PulsesperburstEditField.Value-1); % interpulsegap Limit

            for i = 1:length(userInput)
                userInput(i) = round(userInput(i) / app.IPG.stepsize) * app.IPG.stepsize;
                if userInput(i) > maxIPG
                    userInput(i) = maxIPG;
                end
            end
            
            Input = join(string(userInput), ", ");   
          
            app.interpulsegaps = userInput;
            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value-sum(app.interpulsegaps), 4);
            UpdateInterpulseGaps(app); % writes the IPG Label 

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Value changed function: WaitPeriodEditField
        function WaitPeriodEditFieldValueChanged(app, event)
            totalGap = app.RateEditField_inms.Value - app.WaitPeriodEditField.Value;
            numGaps = app.PulsesperburstEditField.Value - 1;
            rawGap = totalGap / numGaps;
          
 
            if numGaps > 0
                snappedIpg = round(rawGap / app.IPG.stepsize) * app.IPG.stepsize;
    
                numIPG = app.PulsesperburstEditField.Value-1;
                if numIPG < 1, numIPG = 1; end
                maxIPG = app.frame_rate_millis*(floor(app.roundedpossibleFrames / numIPG) * numIPG)/(app.PulsesperburstEditField.Value-1); % interpulsegap Limit
    
                if snappedIpg > maxIPG, snappedIpg = maxIPG; end

            else
                % Single pulse logic: Gap is effectively zero or N/A
                snappedIpg = 0;
            end

            app.InterpulsegapEditField.Value = round(snappedIpg, 4); 
            app.interpulsegaps(:) = snappedIpg; % all of ipg array gets set to 
            

            app.WaitPeriodEditField.Value = round(app.RateEditField_inms.Value - (snappedIpg * numGaps), 4);

            UpdateInterpulseGaps(app); % writes the IPG Label 
            app.IPG.isChanged = 1;

            GenerateSequenceZoomedPlot(app);
            GenerateFileName(app);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            
            mainfolder = app.Directory;
            subfolder = app.brustsfolder;

            specifiedfolder = fullfile(mainfolder, subfolder);

            if ~exist(specifiedfolder, "dir")
                mkdir(specifiedfolder)
            end

            burst_struct = GenerateBurstStruct(app);
            defaultLocation = fullfile(specifiedfolder, strcat(app.FilenameEditField.Value, '.mat'));
            
            % 1. Open the dialog
            [file, folder] = uiputfile(defaultLocation, 'Save Burst Structure');
            
            % 2. Check if the user actually selected a file (did not click cancel)
            if ischar(file) || isstring(file)
                % 3. Use fullfile for cross-platform compatibility (Windows/Mac/Linux)
                fullSavePath = fullfile(folder, file);
                
                % 4. Save the variable
                save(fullSavePath, 'burst_struct');
                
                % Optional: Provide feedback to the user
                fprintf('Burst_struct file saved successfully: %s\n', fullSavePath);
            else
                % User clicked cancel
                disp('Save cancelled by user.');
            end
        end

        % Value changed function: ZoomfactorSpinner
        function ZoomfactorSpinnerValueChanged(app, event)
            GenerateSequenceZoomedPlot(app);
        end

        % Button pushed function: resetMapButton
        function resetMapButtonPushed(app, event)
            resetMapValues(app, "Comfort", "ComfortValues");
            resetMapValues(app, "Threshold", "ThresholdValues");
            resetMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues")
        end

        % Button pushed function: resetValuesButton
        function resetValuesButtonPushed(app, event)
            resetMapValues(app, "Comfort", "ComfortValues");
            resetMapValues(app, "Threshold", "ThresholdValues");
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;

            modeMap = containers.Map({'MP1','MP2','MP1+2'}, [-1, -2, -3]);
            if isKey(modeMap, value)
                app.modes = modeMap(value);
            end

        end

        % Value changed function: CalculateasDynamicRangeofMapCheckBox
        function CalculateasDynamicRangeofMapCheckBoxValueChanged(app, event)
            value = app.CalculateasDynamicRangeofMapCheckBox.Value;
            app.isactiveDynamicRange = value;

            % set limit for all amplitude buttons up to 255 if unchecked 
            % set limit to 1 if checked 
            % set and update all amplitude values and limits 
        end

        % Value changed function: TestSeq_Electrodes
        function TestSeq_ElectrodesValueChanged(app, event)
        persistent OldTestSeq_Electrodes
        value = app.TestSeq_Electrodes.Value;
        
        if ~isempty(app.TestSeq.File) && isfield(app.TestSeq.File, 'burst_struct')

            numPulses = nnz(app.TestSeq.File.burst_struct);

            if isempty(OldTestSeq_Electrodes)
                electrodes = app.Initiate_Electrodes.Value; 
                OldTestSeq_Electrodes = repmat({electrodes}, 1, numPulses);
            end

            electrodesList = str2double(split(value, ','));
            electrodesList = max(1, min(22, electrodesList(isnan(electrodesList)==0)));  % Remove NaN
            
            
            % fprintf('electrodesList length: %d, numPulses: %d\n', length(electrodesList), numPulses);
            
            if ~isempty(electrodesList) && numPulses > 0
                numMultiples = ceil(numPulses / length(electrodesList));  % Use ceil to avoid truncation
                
                % Generate exactly numPulses elements
                electrodeList = repmat(num2cell(electrodesList), 1, numMultiples);
                electrodeList = electrodeList(1:numPulses);  % Trim to exact length
                
                numericelectrodeList = cell2mat(electrodeList);

                if any(app.FlagElectrodeCheckBoxValues(numericelectrodeList))
                    app.TestSeq_Electrodes.Value = strjoin(string(OldTestSeq_Electrodes), ',');
                    return;
                end
                
                app.TestSeq_Electrodes.Value = strjoin(string(electrodeList), ',');

                OldTestSeq_Electrodes = electrodeList;
            end

            GenerateTestSeq(app, app.TestSeq.File);
            % should also work for single input without muliplying 
            % also check issue with if 1 is flagged 
        end
        end

        % Button pushed function: PickFilesButton
        function PickFilesButtonPushed(app, event)
            persistent specifiedfolder
            
            mainfolder = app.Directory;
            subfolder = app.brustsfolder;

            if isempty(specifiedfolder)
                specifiedfolder = fullfile(mainfolder, subfolder);
            end
            
            [fileNames, pathName] = uigetfile( ...
                {'*.mat','MAT files (*.mat)'}, ...
                'Select Multiple Files', ...
                'MultiSelect', 'on' , specifiedfolder);

            % Handle cancel
            if isequal(fileNames, 0)
                return;
            end

            % Process files
            if iscell(fileNames)  % Multiple files
                burstFile = struct('data', {}, 'filename', {});  % Preallocate struct array
                for i = 1:length(fileNames)
                    fullPath = fullfile(pathName, fileNames{i}); % Process fullPath
                    fprintf('Loading: %s\n', fullPath);
                    burstFile(i).data = load(fullPath);  % Data structure
                    burstFile(i).filename = fileNames{i};    % Keep filename

                    SetTestSeqInputs(app, "off",  app.TestSeq.File) % will cause error bcs of no file 
                end
            else  % Single file (still returned as char, not cell)
                fullPath = fullfile(pathName, fileNames); % Process fullPath
                fprintf('Loading: %s\n', fullPath);
                burstFile(1).data = load(fullPath);  % Data structure
                burstFile(1).filename = fileNames;    % Keep filename

                app.TestSeq_Filename.Text = "File: " +string(fileNames);

                app.TestSeq.File = burstFile(1).data; % for now to check electrodes

                numPulses = nnz(burstFile(1).data.burst_struct);

                electrodesList = str2double(split(app.TestSeq_Electrodes.Value, ','));
                amplitudesList = str2double(split(app.TestSeq_Amplitudes.Value, ','));

                SetTestSeqInputs(app, "on", app.TestSeq.File)

                if electrodesList ~= numPulses
                    for i = 1:length(app.TestSeq.File)
                        electrodesDisplay = SetElectrodesInput(app, app.TestSeq.File(i));
                        app.TestSeq_Electrodes.Value = electrodesDisplay;
                    end
                end
                if amplitudesList ~= numPulses
                    for i = 1:length(app.TestSeq.File)
                        amplitudesDisplay = SetAmplitudesInput(app, app.TestSeq.File(i));
                        app.TestSeq_Amplitudes.Value = amplitudesDisplay;
                    end
                end
                GenerateTestSeq(app, app.TestSeq.File);

            end

            % AFTER loop: Set unified properties ONCE with ALL files
            app.Unified.File = burstFile;              % Full array [file1 file2 file3...]
            app.Unified.Filename = {burstFile.filename};   % Cell array of ALL filenames
            
            % Now populate tables with ALL files
            % InitiateFileTable(app, 'Discrimination');
            % InitiateFileTable(app, 'Ranking');
            InitiateFileTable(app, app.Unified.File);  % BOTH tables populate instantly

            specifiedfolder = pathName;  % Remember for next time
        end

        % Value changed function: TransfertoNICCheckBox
        function TransfertoNICCheckBoxValueChanged(app, event)
            value = app.TransfertoNICCheckBox.Value;
            app.isactiveTransfer = value;
        end

        % Value changed function: RampingCheckBox
        function RampingCheckBoxValueChanged(app, event)
            value = app.RampingCheckBox.Value;
            app.isactiveRamping = value;

            if app.isactiveRamping
                app.RampingLength_inmsEditField.Enable = 'on';
            else
                app.RampingLength_inmsEditField.Enable = 'off';
            end
        end

        % Value changed function: GenerateLogCheckBox
        function GenerateLogCheckBoxValueChanged(app, event)
            value = app.GenerateLogCheckBox.Value;
            app.isactiveLogging = value;
        end

        % Value changed function: TestSeq_Amplitudes
        function TestSeq_AmplitudesValueChanged(app, event)
            value = app.TestSeq_Amplitudes.Value;
            

            if ~isempty(app.TestSeq.File) && isfield(app.TestSeq.File, 'burst_struct')
                amplitudesList = str2double(split(value, ','));

                if app.isactiveDynamicRange
                    amplitudesList = max(0, min(1, amplitudesList(isnan(amplitudesList)==0)));  % Remove NaN
                else
                    amplitudesList = max(1, min(255, amplitudesList(isnan(amplitudesList)==0)));  % Remove NaN
                end

                numPulses = nnz(app.TestSeq.File.burst_struct);
                % fprintf('electrodesList length: %d, numPulses: %d\n', length(electrodesList), numPulses);
                
                if ~isempty(amplitudesList) && numPulses > 0
                    numMultiples = ceil(numPulses / length(amplitudesList));
                    
                    % Keep NUMERIC - repeat and trim
                    amplitudesList = repmat(amplitudesList, 1, numMultiples);
                    amplitudesList = amplitudesList(1:numPulses);  % Now 1xnumPulses double array
                    
                    if ~app.isactiveDynamicRange
                        electrodesList = str2double(split(app.TestSeq_Electrodes.Value, ','));
                        electrodesList = repmat(electrodesList, 1, numMultiples);
                        electrodesList = electrodesList(1:numPulses);
                        
                        % Vectorized lookup - get comfort/threshold for each electrode
                        thresholdValues = arrayfun(@(e) app.ThresholdValues(e), electrodesList);
                        comfortValues = arrayfun(@(e) app.ComfortValues(e), electrodesList);
                        
                        % Now clamp each amplitude with its electrode's limits
                        validIdx = ~isnan(amplitudesList);
                        amplitudesList(validIdx) = max(thresholdValues(validIdx), ...
                                                      min(comfortValues(validIdx), amplitudesList(validIdx)));
                    end
                    % Convert to string for UI
                    app.TestSeq_Amplitudes.Value = strjoin(string(amplitudesList), ',');
                end
                GenerateTestSeq(app, app.TestSeq.File);
            end

        end

        % Button pushed function: TestSeq_ResetElectrodes
        function TestSeq_ResetElectrodesButtonPushed(app, event)
            if ~isempty(app.TestSeq.File)
                SetElectrodesInput(app, app.TestSeq.File)
            else
                app.TestSeq_Electrodes.Value = app.Initiate_Electrodes.Value; 
            end
        end

        % Button pushed function: Testseq_ResetAmplitudes
        function Testseq_ResetAmplitudesButtonPushed(app, event)
            if ~isempty(app.TestSeq.File)
                SetAmplitudesInput(app, app.TestSeq.File)
            else
                app.TestSeq_Amplitudes.Value = app.TestSeq_Amplitudes.Value; 
            end
        end

        % Button down function: RankingExpTab
        function RankingExpTabButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OversightTabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.OversightTabGroup);
            app.MainTab.Title = 'Main';
            app.PatientIDEditFieldLabel = uilabel(app.MainTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.MainTab, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.PatientIDEditField.Value = 'insert_ID';
            app.ExperimentTabGroup = uitabgroup(app.MainTab);
            app.TestSequenceTab = uitab(app.ExperimentTabGroup);
            app.TestSequenceTab.Title = 'Test Sequence';
            app.TestSeq_Plot = uiaxes(app.TestSequenceTab);
            title(app.TestSeq_Plot, 'Sequence')
            xlabel(app.TestSeq_Plot, 'Time [ms]')
            ylabel(app.TestSeq_Plot, 'Amplitude [CL]')
            app.TestSeq_Filename = uilabel(app.TestSequenceTab);
            app.TestSeq_Filename.Text = 'File: ';
            app.TestSeq_Run = uibutton(app.TestSequenceTab, 'push');
            app.TestSeq_Run.Enable = 'off';
            app.TestSeq_Run.Text = 'Run';
            app.TestSeq_Electrodes = uieditfield(app.TestSequenceTab, 'text');
            app.TestSeq_Electrodes.ValueChangedFcn = createCallbackFcn(app, @TestSeq_ElectrodesValueChanged, true);
            app.TestSeq_Electrodes.Enable = 'off';
            app.TestSeq_Electrodes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.TestSeq_Electrodes.Value = '1';
            app.InterpulsegapmsLabel_2 = uilabel(app.TestSequenceTab);
            app.InterpulsegapmsLabel_2.Text = 'Electrodes';
            app.TestSeq_Amplitudes = uieditfield(app.TestSequenceTab, 'text');
            app.TestSeq_Amplitudes.ValueChangedFcn = createCallbackFcn(app, @TestSeq_AmplitudesValueChanged, true);
            app.TestSeq_Amplitudes.Enable = 'off';
            app.TestSeq_Amplitudes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.TestSeq_Amplitudes.Value = '0.8';
            app.InterpulsegapmsLabel_3 = uilabel(app.TestSequenceTab);
            app.InterpulsegapmsLabel_3.Text = 'Amplitudes';
            app.TestSeq_ResetElectrodes = uibutton(app.TestSequenceTab, 'push');
            app.TestSeq_ResetElectrodes.ButtonPushedFcn = createCallbackFcn(app, @TestSeq_ResetElectrodesButtonPushed, true);
            app.TestSeq_ResetElectrodes.Enable = 'off';
            app.TestSeq_ResetElectrodes.Text = 'reset';
            app.Testseq_ResetAmplitudes = uibutton(app.TestSequenceTab, 'push');
            app.Testseq_ResetAmplitudes.ButtonPushedFcn = createCallbackFcn(app, @Testseq_ResetAmplitudesButtonPushed, true);
            app.Testseq_ResetAmplitudes.Enable = 'off';
            app.Testseq_ResetAmplitudes.Text = 'reset';
            app.TestSeq_PulsesLabel = uilabel(app.TestSequenceTab);
            app.TestSeq_PulsesLabel.Text = 'Pulses in Burst File: ';
            app.DiscrimationExpTab = uitab(app.ExperimentTabGroup);
            app.DiscrimationExpTab.Title = 'Discrimation Exp';
            app.Discrimination_Plot = uiaxes(app.DiscrimationExpTab);
            title(app.Discrimination_Plot, 'Sequence')
            xlabel(app.Discrimination_Plot, 'Time [ms]')
            ylabel(app.Discrimination_Plot, 'Amplitude [CL]')
            app.Continue_ListBox = uilistbox(app.DiscrimationExpTab);
            app.Continue_ListBox.Enable = 'off';
            app.Continue_ListBox.Visible = 'off';
            app.Discrimination_ContinueButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_ContinueButton.Text = 'Continue';
            app.Discrimination_AddfilesButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_AddfilesButton.Text = 'Add files';
            app.Continue_RunButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Continue_RunButton.Enable = 'off';
            app.Continue_RunButton.Visible = 'off';
            app.Continue_RunButton.Text = 'Run';
            app.PuttextforListLabel = uilabel(app.DiscrimationExpTab);
            app.PuttextforListLabel.Visible = 'off';
            app.PuttextforListLabel.Text = 'Put text for List';
            app.RunsLabel_2 = uilabel(app.DiscrimationExpTab);
            app.RunsLabel_2.Text = 'Runs:';
            app.Discrimination_RunsEditField = uieditfield(app.DiscrimationExpTab, 'text');
            app.Discrimination_RuntimeLabel = uilabel(app.DiscrimationExpTab);
            app.Discrimination_RuntimeLabel.Text = 'Estimated Runtime:';
            app.Discrimination_Table = uitable(app.DiscrimationExpTab);
            app.Discrimination_Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Discrimination_Table.RowName = {};
            app.Continue_ReturnButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Continue_ReturnButton.Enable = 'off';
            app.Continue_ReturnButton.Visible = 'off';
            app.Continue_ReturnButton.Text = 'Return';
            app.Discrimination_SaveTableButton = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_SaveTableButton.Text = 'Save Table';
            app.Discrimination_TablenameLabel = uilabel(app.DiscrimationExpTab);
            app.Discrimination_TablenameLabel.Text = 'Table:';
            app.Discrimination_ClearTable = uibutton(app.DiscrimationExpTab, 'push');
            app.Discrimination_ClearTable.Text = 'Clear Table';
            app.RankingExpTab = uitab(app.ExperimentTabGroup);
            app.RankingExpTab.Title = 'Ranking Exp';
            app.RankingExpTab.ButtonDownFcn = createCallbackFcn(app, @RankingExpTabButtonDown, true);
            app.Ranking_Plot = uiaxes(app.RankingExpTab);
            title(app.Ranking_Plot, 'Sequence')
            xlabel(app.Ranking_Plot, 'Time [ms]')
            ylabel(app.Ranking_Plot, 'Amplitude [CL]')
            app.Ranking_RunButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_RunButton.Text = 'Run';
            app.RunsLabel = uilabel(app.RankingExpTab);
            app.RunsLabel.Text = 'Runs:';
            app.Ranking_RunsEditField = uieditfield(app.RankingExpTab, 'text');
            app.Ranking_AddfilesButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_AddfilesButton.Text = 'Add files';
            app.Ranking_Table = uitable(app.RankingExpTab);
            app.Ranking_Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Ranking_Table.RowName = {};
            app.Ranking_RuntimeLabel = uilabel(app.RankingExpTab);
            app.Ranking_RuntimeLabel.Text = 'Estimated Runtime:';
            app.Ranking_SaveTableButton = uibutton(app.RankingExpTab, 'push');
            app.Ranking_SaveTableButton.Text = 'Save Table';
            app.Ranking_TablenameLabel = uilabel(app.RankingExpTab);
            app.Ranking_TablenameLabel.Text = 'Table:';
            app.Ranking_ClearTable = uibutton(app.RankingExpTab, 'push');
            app.Ranking_ClearTable.Text = 'Clear Table';
            app.Threshold_1 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_1.Limits = [0 255];
            app.Threshold_2 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_2.Limits = [0 255];
            app.Threshold_3 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_3.Limits = [0 255];
            app.Threshold_4 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_4.Limits = [0 255];
            app.Threshold_5 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_5.Limits = [0 255];
            app.Threshold_6 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_6.Limits = [0 255];
            app.Threshold_7 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_7.Limits = [0 255];
            app.Threshold_8 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_8.Limits = [0 255];
            app.Threshold_9 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_9.Limits = [0 255];
            app.Threshold_10 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_10.Limits = [0 255];
            app.Threshold_11 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_11.Limits = [0 255];
            app.Threshold_12 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_12.Limits = [0 255];
            app.Threshold_13 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_13.Limits = [0 255];
            app.Threshold_14 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_14.Limits = [0 255];
            app.Threshold_15 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_15.Limits = [0 255];
            app.Threshold_16 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_16.Limits = [0 255];
            app.Threshold_17 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_17.Limits = [0 255];
            app.Threshold_18 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_18.Limits = [0 255];
            app.Threshold_19 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_19.Limits = [0 255];
            app.Threshold_20 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_20.Limits = [0 255];
            app.Threshold_21 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_21.Limits = [0 255];
            app.Threshold_22 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_22.Limits = [0 255];
            app.Comfort_1 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_1.Limits = [0 255];
            app.Comfort_1.Value = 255;
            app.Comfort_2 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_2.Limits = [0 255];
            app.Comfort_2.Value = 255;
            app.Comfort_3 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_3.Limits = [0 255];
            app.Comfort_3.Value = 255;
            app.Comfort_4 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_4.Limits = [0 255];
            app.Comfort_4.Value = 255;
            app.Comfort_5 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_5.Limits = [0 255];
            app.Comfort_5.Value = 255;
            app.Comfort_6 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_6.Limits = [0 255];
            app.Comfort_6.Value = 255;
            app.Comfort_7 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_7.Limits = [0 255];
            app.Comfort_7.Value = 255;
            app.Comfort_8 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_8.Limits = [0 255];
            app.Comfort_8.Value = 255;
            app.Comfort_9 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_9.Limits = [0 255];
            app.Comfort_9.Value = 255;
            app.Comfort_10 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_10.Limits = [0 255];
            app.Comfort_10.Value = 255;
            app.Comfort_11 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_11.Limits = [0 255];
            app.Comfort_11.Value = 255;
            app.Comfort_12 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_12.Limits = [0 255];
            app.Comfort_12.Value = 255;
            app.Comfort_13 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_13.Limits = [0 255];
            app.Comfort_13.Value = 255;
            app.Comfort_14 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_14.Limits = [0 255];
            app.Comfort_14.Value = 255;
            app.Comfort_15 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_15.Limits = [0 255];
            app.Comfort_15.Value = 255;
            app.Comfort_16 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_16.Limits = [0 255];
            app.Comfort_16.Value = 255;
            app.Comfort_17 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_17.Limits = [0 255];
            app.Comfort_17.Value = 255;
            app.Comfort_18 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_18.Limits = [0 255];
            app.Comfort_18.Value = 255;
            app.Comfort_19 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_19.Limits = [0 255];
            app.Comfort_19.Value = 255;
            app.Comfort_20 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_20.Limits = [0 255];
            app.Comfort_20.Value = 255;
            app.Comfort_21 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_21.Limits = [0 255];
            app.Comfort_21.Value = 255;
            app.Comfort_22 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_22.Limits = [0 255];
            app.Comfort_22.Value = 255;
            app.ThresholdLabel = uilabel(app.MainTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.ComfortLabel = uilabel(app.MainTab);
            app.ComfortLabel.Text = 'Comfort';
            app.FlaggedLabel = uilabel(app.MainTab);
            app.FlaggedLabel.Text = 'Flagged';
            app.FlagElectrodeCheckBox_1 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_1.Text = '1';
            app.FlagElectrodeCheckBox_2 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_2.Text = '2';
            app.FlagElectrodeCheckBox_3 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_3.Text = '3';
            app.FlagElectrodeCheckBox_4 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_4.Text = '4';
            app.FlagElectrodeCheckBox_5 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_5.Text = '5';
            app.FlagElectrodeCheckBox_6 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_6.Text = '6';
            app.FlagElectrodeCheckBox_7 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_7.Text = '7';
            app.FlagElectrodeCheckBox_8 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_8.Text = '8';
            app.FlagElectrodeCheckBox_9 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_9.Text = '9';
            app.FlagElectrodeCheckBox_10 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_10.Text = '10';
            app.FlagElectrodeCheckBox_11 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_11.Text = '11';
            app.FlagElectrodeCheckBox_12 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_12.Text = '12';
            app.FlagElectrodeCheckBox_13 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_13.Text = '13';
            app.FlagElectrodeCheckBox_14 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_14.Text = '14';
            app.FlagElectrodeCheckBox_15 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_15.Text = '15';
            app.FlagElectrodeCheckBox_16 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_16.Text = '16';
            app.FlagElectrodeCheckBox_17 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_17.Text = '17';
            app.FlagElectrodeCheckBox_18 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_18.Text = '18';
            app.FlagElectrodeCheckBox_19 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_19.Text = '19';
            app.FlagElectrodeCheckBox_20 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_20.Text = '20';
            app.FlagElectrodeCheckBox_21 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_21.Text = '21';
            app.FlagElectrodeCheckBox_22 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_22.Text = '22';
            app.FlagunflagallElectrodesCheckBox = uicheckbox(app.MainTab);
            app.FlagunflagallElectrodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlagunflagallElectrodesCheckBoxValueChanged, true);
            app.FlagunflagallElectrodesCheckBox.Text = 'Flag/unflag all Electrodes';
            app.importMapButton = uibutton(app.MainTab, 'push');
            app.importMapButton.Enable = 'off';
            app.importMapButton.Text = 'import Map';
            app.DirectoryLabel = uilabel(app.MainTab);
            app.DirectoryLabel.Text = 'Directory: ';
            app.LoginButton = uibutton(app.MainTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Log in ';
            app.SelectDirectoryButton = uibutton(app.MainTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.resetMapButton = uibutton(app.MainTab, 'push');
            app.resetMapButton.ButtonPushedFcn = createCallbackFcn(app, @resetMapButtonPushed, true);
            app.resetMapButton.Text = 'reset Map';
            app.resetValuesButton = uibutton(app.MainTab, 'push');
            app.resetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @resetValuesButtonPushed, true);
            app.resetValuesButton.Text = 'reset Values';
            app.PickFilesButton = uibutton(app.MainTab, 'push');
            app.PickFilesButton.ButtonPushedFcn = createCallbackFcn(app, @PickFilesButtonPushed, true);
            app.PickFilesButton.Text = 'Pick Files';
            app.PickTableButton = uibutton(app.MainTab, 'push');
            app.PickTableButton.Enable = 'off';
            app.PickTableButton.Text = 'Pick Table';
            app.GenBurstTab = uitab(app.OversightTabGroup);
            app.GenBurstTab.Title = 'Gen. Burst';
            app.Burst_Example = uiaxes(app.GenBurstTab);
            title(app.Burst_Example, 'Zoomed view')
            xlabel(app.Burst_Example, 'Time [ms]')
            ylabel(app.Burst_Example, 'Amplitude [CL]')
            app.SaveAsButton = uibutton(app.GenBurstTab, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Click "Graph" before saving to generate the correct .mat files'};
            app.SaveAsButton.Text = 'Save As:';
            app.FilenameEditField = uieditfield(app.GenBurstTab, 'text');
            app.FilenameEditField.Value = 'Insert_Filename';
            app.InputgapsButton = uibutton(app.GenBurstTab, 'push');
            app.InputgapsButton.ButtonPushedFcn = createCallbackFcn(app, @InputgapsButtonPushed, true);
            app.InputgapsButton.Enable = 'off';
            app.InputgapsButton.Text = 'Input gaps';
            app.BurstperiodppsLabel = uilabel(app.GenBurstTab);
            app.BurstperiodppsLabel.Text = 'Rate [pps]';
            app.RateEditField_inpps = uieditfield(app.GenBurstTab, 'numeric');
            app.RateEditField_inpps.Limits = [0 15432.0988];
            app.RateEditField_inpps.ValueDisplayFormat = '%.0f';
            app.RateEditField_inpps.ValueChangedFcn = createCallbackFcn(app, @RateEditField_inppsValueChanged, true);
            app.RateEditField_inpps.Tooltip = {'Enter burst period in ms'};
            app.RateEditField_inpps.Value = 900;
            app.InterpulsegapmsLabel = uilabel(app.GenBurstTab);
            app.InterpulsegapmsLabel.Text = 'Interpulsegap [ms]';
            app.PulsesperFrameLabel = uilabel(app.GenBurstTab);
            app.PulsesperFrameLabel.Text = 'Pulses per Burst';
            app.PulsesperburstEditField = uieditfield(app.GenBurstTab, 'numeric');
            app.PulsesperburstEditField.Limits = [1 Inf];
            app.PulsesperburstEditField.RoundFractionalValues = 'on';
            app.PulsesperburstEditField.ValueDisplayFormat = '%.0f';
            app.PulsesperburstEditField.ValueChangedFcn = createCallbackFcn(app, @PulsesperburstEditFieldValueChanged, true);
            app.PulsesperburstEditField.Tooltip = {'Enter number of pulses within a burst. '; 'NOTE: If only 1 pulse is entered, interpulsegap will be ignored'};
            app.PulsesperburstEditField.Value = 1;
            app.InterpulsegapEditField = uieditfield(app.GenBurstTab, 'numeric');
            app.InterpulsegapEditField.Limits = [0 Inf];
            app.InterpulsegapEditField.ValueDisplayFormat = '%.4f';
            app.InterpulsegapEditField.ValueChangedFcn = createCallbackFcn(app, @InterpulsegapEditFieldValueChanged, true);
            app.InterpulsegapEditField.Enable = 'off';
            app.InterpulsegapEditField.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.InterpulsegapEditField.Value = 0.0648;
            app.BurstGapmsLabel = uilabel(app.GenBurstTab);
            app.BurstGapmsLabel.Text = 'Wait-Period [ms]';
            app.WaitPeriodEditField = uieditfield(app.GenBurstTab, 'numeric');
            app.WaitPeriodEditField.Limits = [0 Inf];
            app.WaitPeriodEditField.ValueDisplayFormat = '%.4f ';
            app.WaitPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @WaitPeriodEditFieldValueChanged, true);
            app.WaitPeriodEditField.Tooltip = {'Enter burst period in ms'};
            app.WaitPeriodEditField.Value = 1.1111;
            app.FrameperiodLabel = uilabel(app.GenBurstTab);
            app.FrameperiodLabel.Text = 'Frame-period: ';
            app.BurstperiodppsLabel_2 = uilabel(app.GenBurstTab);
            app.BurstperiodppsLabel_2.Text = 'Rate [ms]';
            app.RateEditField_inms = uieditfield(app.GenBurstTab, 'numeric');
            app.RateEditField_inms.Limits = [0.0666 Inf];
            app.RateEditField_inms.ValueDisplayFormat = '%.4f';
            app.RateEditField_inms.ValueChangedFcn = createCallbackFcn(app, @RateEditField_inmsValueChanged, true);
            app.RateEditField_inms.Tooltip = {'Enter burst period in ms'};
            app.RateEditField_inms.Value = 1.1111;
            app.MaxPulsesLabel = uilabel(app.GenBurstTab);
            app.MaxPulsesLabel.Text = 'Max Pulses: ';
            app.IPGDisplay = uilabel(app.GenBurstTab);
            app.IPGDisplay.Text = '';
            app.IPGInfo_1 = uilabel(app.GenBurstTab);
            app.IPGInfo_1.Text = 'Make sure to use multiples of 2 for the generation of the IPGs as uneven numbers are bad for moving the IPGs later';
            app.IPGInfo_2 = uilabel(app.GenBurstTab);
            app.IPGInfo_2.Text = 'The IPG is the actual interpulsegap + 1 pulse (being one frame). same with Wait-Period.';
            app.ZoomfactorSpinnerLabel = uilabel(app.GenBurstTab);
            app.ZoomfactorSpinnerLabel.Text = 'Zoomfactor';
            app.ZoomfactorSpinner = uispinner(app.GenBurstTab);
            app.ZoomfactorSpinner.Limits = [0 Inf];
            app.ZoomfactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ZoomfactorSpinnerValueChanged, true);
            app.ZoomfactorSpinner.Value = 4;
            app.DataAnalysisTab = uitab(app.OversightTabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.giveinputdataButton = uibutton(app.DataAnalysisTab, 'push');
            app.giveinputdataButton.Text = 'give input data';
            app.PatientNameEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.FileorFoldertouseEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.FileorFoldertouseEditFieldLabel.Text = 'File or Folder to use';
            app.FileorFoldertouseEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel = uilabel(app.DataAnalysisTab);
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel.Text = 'usually directory like normally + dis or ranking log folder ';
            app.TabGroup = uitabgroup(app.DataAnalysisTab);
            app.DiscriminationPlotTab = uitab(app.TabGroup);
            app.DiscriminationPlotTab.Title = 'Discrimination Plot ';
            app.RankingPlotTab = uitab(app.TabGroup);
            app.RankingPlotTab.Title = 'Ranking Plot';
            app.UIAxes2 = uiaxes(app.RankingPlotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.createPlotButton = uibutton(app.RankingPlotTab, 'push');
            app.createPlotButton.Text = 'create Plot';
            app.nameofLogorderorsoLabel = uilabel(app.RankingPlotTab);
            app.nameofLogorderorsoLabel.Text = 'name of Log order or so ';
            app.needautomatedlognamingLabel = uilabel(app.RankingPlotTab);
            app.needautomatedlognamingLabel.Text = 'need automated log naming ';
            app.AppSettingsTab = uitab(app.OversightTabGroup);
            app.AppSettingsTab.Title = 'App Settings';
            app.CalculateasDynamicRangeofMapCheckBox = uicheckbox(app.AppSettingsTab);
            app.CalculateasDynamicRangeofMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculateasDynamicRangeofMapCheckBoxValueChanged, true);
            app.CalculateasDynamicRangeofMapCheckBox.Text = 'Calculate as Dynamic Range of Map';
            app.CalculateasDynamicRangeofMapCheckBox.Value = true;
            app.RampingCheckBox = uicheckbox(app.AppSettingsTab);
            app.RampingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RampingCheckBoxValueChanged, true);
            app.RampingCheckBox.Text = 'Ramping ';
            app.RampingCheckBox.Value = true;
            app.Decideafter2unanimousDecisionsCheckBox = uicheckbox(app.AppSettingsTab);
            app.Decideafter2unanimousDecisionsCheckBox.Enable = 'off';
            app.Decideafter2unanimousDecisionsCheckBox.Text = 'Decide after 2 unanimous Decisions';
            app.Decideafter2unanimousDecisionsCheckBox.Value = true;
            app.PitchRankingExperimentLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingExperimentLabel.Text = 'Pitch Ranking Experiment:';
            app.PitchRankingDecisionRepetitionEditFieldLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingDecisionRepetitionEditFieldLabel.Enable = 'off';
            app.PitchRankingDecisionRepetitionEditFieldLabel.Text = 'Pitch Ranking Decision Repetition ';
            app.PitchRankingDecisionRepetitionEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.PitchRankingDecisionRepetitionEditField.Limits = [0 Inf];
            app.PitchRankingDecisionRepetitionEditField.RoundFractionalValues = 'on';
            app.PitchRankingDecisionRepetitionEditField.Enable = 'off';
            app.PitchRankingDecisionRepetitionEditField.Value = 6;
            app.StimphasesEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimphasesEditFieldLabel.Enable = 'off';
            app.StimphasesEditFieldLabel.Text = 'Stim phase [µs]';
            app.StimphaseEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.StimphaseEditField.Limits = [0 Inf];
            app.StimphaseEditField.ValueDisplayFormat = '%.0f';
            app.StimphaseEditField.Enable = 'off';
            app.StimphaseEditField.Tooltip = {'Enter phase width in µs'};
            app.StimphaseEditField.Value = 25;
            app.InterphasegapsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.InterphasegapsEditFieldLabel.Enable = 'off';
            app.InterphasegapsEditFieldLabel.Text = 'Interphase gap [µs]';
            app.InterphasegapsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.InterphasegapsEditField.Limits = [0 Inf];
            app.InterphasegapsEditField.ValueDisplayFormat = '%.0f';
            app.InterphasegapsEditField.Enable = 'off';
            app.InterphasegapsEditField.Tooltip = {'Enter interphase gap in µs'};
            app.InterphasegapsEditField.Value = 7;
            app.StimdurationmsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimdurationmsEditFieldLabel.Text = 'Stim duration [ms]';
            app.Stimduration_inmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.Stimduration_inmsEditField.Limits = [0 Inf];
            app.Stimduration_inmsEditField.Tooltip = {'Enter stim duration in ms'};
            app.Stimduration_inmsEditField.Value = 500;
            app.TransfertoNICCheckBox = uicheckbox(app.AppSettingsTab);
            app.TransfertoNICCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransfertoNICCheckBoxValueChanged, true);
            app.TransfertoNICCheckBox.Text = 'Transfer to NIC';
            app.TransfertoNICCheckBox.Value = true;
            app.ClevelsPulsewidthEditFieldLabel = uilabel(app.AppSettingsTab);
            app.ClevelsPulsewidthEditFieldLabel.Enable = 'off';
            app.ClevelsPulsewidthEditFieldLabel.Text = 'C levels Pulse width';
            app.ClevelsPulsewidthEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.ClevelsPulsewidthEditField.Enable = 'off';
            app.ClevelsPulsewidthEditField.Value = 25;
            app.IntersequencemsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.IntersequencemsEditFieldLabel.Text = 'Intersequence [ms]';
            app.Intersequence_inmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.Intersequence_inmsEditField.Value = 500;
            app.ModeDropDown_2Label = uilabel(app.AppSettingsTab);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AppSettingsTab);
            app.ModeDropDown.Items = {'MP1', 'MP2', 'MP1+2'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'MP1+2';
            app.GenerateLogCheckBox = uicheckbox(app.AppSettingsTab);
            app.GenerateLogCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateLogCheckBoxValueChanged, true);
            app.GenerateLogCheckBox.Text = 'Generate Log';
            app.GenerateLogCheckBox.Value = true;
            app.OverallLabel = uilabel(app.AppSettingsTab);
            app.OverallLabel.Text = 'Overall';
            app.SequenceGenerationLabel = uilabel(app.AppSettingsTab);
            app.SequenceGenerationLabel.Text = 'Sequence Generation';
            app.BuildframerateLabel = uilabel(app.AppSettingsTab);
            app.BuildframerateLabel.Text = 'Build framerate';
            app.RandomTokenOrderCheckBox = uicheckbox(app.AppSettingsTab);
            app.RandomTokenOrderCheckBox.Text = 'Random Token Order';
            app.TokenpatternDropDownLabel = uilabel(app.AppSettingsTab);
            app.TokenpatternDropDownLabel.Text = 'Token pattern ';
            app.TokenpatternDropDown = uidropdown(app.AppSettingsTab);
            app.TokenpatternDropDown.Items = {'Odd Token Out ', 'A - B ', '4I2AFC'};
            app.TokenpatternDropDown.Value = 'Odd Token Out ';
            app.TokenOrderDropDownLabel = uilabel(app.AppSettingsTab);
            app.TokenOrderDropDownLabel.Text = 'Token Order';
            app.TokenOrderDropDown = uidropdown(app.AppSettingsTab);
            app.TokenOrderDropDown.Items = {'A - B - A', 'B - A - A', 'A - A - B'};
            app.TokenOrderDropDown.Value = 'A - B - A';
            app.InterpulsegapmsLabel_4 = uilabel(app.AppSettingsTab);
            app.InterpulsegapmsLabel_4.Text = 'Initiate Electrodes';
            app.Initiate_Electrodes = uieditfield(app.AppSettingsTab, 'text');
            app.Initiate_Electrodes.CharacterLimits = [1 22];
            app.Initiate_Electrodes.InputType = 'digits';
            app.Initiate_Electrodes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.Initiate_Electrodes.Value = '1';
            app.Initiate_Amplitudes = uieditfield(app.AppSettingsTab, 'numeric');
            app.Initiate_Amplitudes.Limits = [0 1];
            app.Initiate_Amplitudes.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.Initiate_Amplitudes.Value = 0.8;
            app.InterpulsegapmsLabel_5 = uilabel(app.AppSettingsTab);
            app.InterpulsegapmsLabel_5.Text = 'Initiate Amplitudes';
            app.RampingLength_inmsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.RampingLength_inmsEditField.Limits = [0 200];
            app.RampingLength_inmsEditField.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.RampingLength_inmsEditField.Value = 10;
            app.InterpulsegapmsLabel_6 = uilabel(app.AppSettingsTab);
            app.InterpulsegapmsLabel_6.Text = 'Ramping length [ms]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tigerv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end